classdef Shooting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        InputsPanel                    matlab.ui.container.Panel
        TileNumberListBoxLabel         matlab.ui.control.Label
        TileNumberListBox              matlab.ui.control.ListBox
        MaxInnerSurfaceTempSpinnerLabel  matlab.ui.control.Label
        MaxInnerSurfaceTempSpinner     matlab.ui.control.Spinner
        MaxAllowableErrorSpinnerLabel  matlab.ui.control.Label
        MaxAllowableErrorSpinner       matlab.ui.control.Spinner
        MaximumTimesSpinnerLabel       matlab.ui.control.Label
        MaximumTimesSpinner            matlab.ui.control.Spinner
        NumberofTimestepsSpinnerLabel  matlab.ui.control.Label
        NumberofTimestepsSpinner       matlab.ui.control.Spinner
        NumberofSpatialStepsSpinnerLabel  matlab.ui.control.Label
        NumberofSpatialStepsSpinner    matlab.ui.control.Spinner
        EnableRadiationCheckBox        matlab.ui.control.CheckBox
        Label                          matlab.ui.control.Label
        FINDTHEIDEALTHICKNESSButton    matlab.ui.control.Button
    end


    methods (Access = private)
    
        function xideal = launchshooter(app, tmax, nt, nx, tilenum, umax, erracceptable, r)
            
            doplot = false; % false to suppress graph.
            method = 'crank-nicolson'; % solution method
            
            % Loading surface temperature data
            imagename = strcat('temp',tilenum);
            imagefile = strcat(imagename,'.mat');
            if exist(imagefile) ~= 2
                plottemp
            end
            load(imagename);
            
            % Setting the initial two thickness guesses
            thickness(1) = 0.01;
            thickness(2) = 0.10;
            
            % Determining the maximum inner surface temperature for the respective
            % value of thickness
            [~,~,~,maxtemp(1)] = shuttlerad(tmax,nt,thickness(1),nx,method,doplot,imagename,r);
            [~,~,~,maxtemp(2)] = shuttlerad(tmax,nt,thickness(2),nx,method,doplot,imagename,r);
            
            % Calculating the error by finding the difference between the computed
            % temperature and the user-selected temperature
            error(1) = maxtemp(1) - umax;
            error(2) = maxtemp(2) - umax;
            
            n = 2; % Starting the counter at a value of 2
            
            % Implementing the shooting method
            
            while abs(error(n)) > erracceptable
            % Using the shooting method to determine subsequent guesses for the launch
            % angle until the calculated error satisfies the necessary condition
                
                % Displaying the guess as the label text
                roundedthick = round(thickness(n),4);
                thicktext = num2str(roundedthick);
                app.Label.Text = thicktext;
                pause(0.1);
            
                % Computing the next angle guess using the previous two angle guesses
                thickness(n+1) = thickness(n)-error(n)*((thickness(n)-thickness(n-1))/(error(n)-error(n-1)));
                
                % Checking to see if the shooting method failed
                if isnan(thickness(n+1))
                    xideal = 'Error';
                    return
                end
              
                % Solving the IVP using the newly guessed thickness to determine the
                % corresponding maximum temperature
                [~,~,~,maxtemp(n+1)] = shuttlerad(tmax,nt,thickness(n+1),nx,method,doplot,imagename,r);
                    
                % Calculating the error, which is stored in a vector
                error(n+1) = maxtemp(n+1) - umax;
                
                % Incrementing the counter by 1
                n = n+1;
                
            end

            xval = abs(thickness(end)); % Thickness required to ensure inner surface temperature specification is met
            xideal = round(xval,4); % Rounding the thickness to 4 decimal places
            
        end
        
    end


    methods (Access = private)

        % Button pushed function: FINDTHEIDEALTHICKNESSButton
        function FINDTHEIDEALTHICKNESSButtonPushed(app, event)
            % Disabling the figure while programs are being run
            set(findall(app.UIFigure, '-property', 'Enable'), 'Enable', 'off')
            
            app.Label.FontColor = [0.93 0.16 0.22]; % Setting label font color to red
            
            tmax = app.MaximumTimesSpinner.Value; % maximum time(s)
            nt = app.NumberofTimestepsSpinner.Value; % number of timesteps
            nx = app.NumberofSpatialStepsSpinner.Value; % number of spatial steps
            tilenum = app.TileNumberListBox.Value; % selected tile number
            umax = app.MaxInnerSurfaceTempSpinner.Value; % maximum allowable inner surface temperature (deg C)
            erracceptable = app.MaxAllowableErrorSpinner.Value; % acceptable error
            r = app.EnableRadiationCheckBox.Value; % boolean to determine if radiation is included or excluded
            
            % Running launchshooter to determine the ideal tile thickness (xideal)
            xideal = launchshooter(app, tmax, nt, nx, tilenum, umax, erracceptable,r);
            
            % Determining whether the ideal thickness was found or an error occurred
            if isnumeric(xideal)
                xtext = num2str(xideal); % Converting the double 'xideal' to a text
                app.Label.FontColor = [0.36 0.76 0.21]; % Setting label font color to green
            else
                xtext = xideal;
            end
            
            app.Label.Text = xtext; % Setting label text to the ideal thickness value
            
            % Enabling the figure after all programs are run
            set(findall(app.UIFigure, '-property', 'Enable'), 'Enable', 'on')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.InputsPanel = uipanel(app.UIFigure);
            app.InputsPanel.Title = 'Inputs';
            app.TileNumberListBoxLabel = uilabel(app.InputsPanel);
            app.TileNumberListBoxLabel.Text = 'Tile Number';
            app.TileNumberListBox = uilistbox(app.InputsPanel);
            app.TileNumberListBox.Items = {'502', '590', '468', '597', '480', '850', '711', '730'};
            app.TileNumberListBox.Value = '502';
            app.MaxInnerSurfaceTempSpinnerLabel = uilabel(app.InputsPanel);
            app.MaxInnerSurfaceTempSpinnerLabel.Text = 'Max Inner Surface Temp';
            app.MaxInnerSurfaceTempSpinner = uispinner(app.InputsPanel);
            app.MaxInnerSurfaceTempSpinner.Limits = [16 Inf];
            app.MaxInnerSurfaceTempSpinner.Value = 176;
            app.MaxAllowableErrorSpinnerLabel = uilabel(app.InputsPanel);
            app.MaxAllowableErrorSpinnerLabel.Text = 'Max Allowable Error';
            app.MaxAllowableErrorSpinner = uispinner(app.InputsPanel);
            app.MaxAllowableErrorSpinner.Step = 0.1;
            app.MaxAllowableErrorSpinner.Limits = [0.1 Inf];
            app.MaxAllowableErrorSpinner.Value = 0.5;
            app.MaximumTimesSpinnerLabel = uilabel(app.InputsPanel);
            app.MaximumTimesSpinnerLabel.Text = 'Maximum Time (s)';
            app.MaximumTimesSpinner = uispinner(app.InputsPanel);
            app.MaximumTimesSpinner.Value = 4000;
            app.NumberofTimestepsSpinnerLabel = uilabel(app.InputsPanel);
            app.NumberofTimestepsSpinnerLabel.Text = 'Number of Timesteps';
            app.NumberofTimestepsSpinner = uispinner(app.InputsPanel);
            app.NumberofTimestepsSpinner.Value = 501;
            app.NumberofSpatialStepsSpinnerLabel = uilabel(app.InputsPanel);
            app.NumberofSpatialStepsSpinnerLabel.Text = 'Number of Spatial Steps';
            app.NumberofSpatialStepsSpinner = uispinner(app.InputsPanel);
            app.NumberofSpatialStepsSpinner.Value = 21;
            app.EnableRadiationCheckBox = uicheckbox(app.InputsPanel);
            app.EnableRadiationCheckBox.Text = 'Enable Radiation';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0.0000';
            app.FINDTHEIDEALTHICKNESSButton = uibutton(app.UIFigure, 'push');
            app.FINDTHEIDEALTHICKNESSButton.ButtonPushedFcn = createCallbackFcn(app, @FINDTHEIDEALTHICKNESSButtonPushed, true);
            app.FINDTHEIDEALTHICKNESSButton.Text = 'FIND THE IDEAL THICKNESS';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Shooting

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end