classdef TempSimulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        WaterfallPlotterMenu           matlab.ui.container.Menu
        StabilityCheckerMenu           matlab.ui.container.Menu
        ThicknessComparerMenu          matlab.ui.container.Menu
        SelectTileDropDownLabel        matlab.ui.control.Label
        SelectTileDropDown             matlab.ui.control.DropDown
        SelectMethodDropDownLabel      matlab.ui.control.Label
        SelectMethodDropDown           matlab.ui.control.DropDown
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        InputsPanel                    matlab.ui.container.Panel
        RUNPROGRAMButton               matlab.ui.control.Button
        CLEARButton                    matlab.ui.control.Button
        NumberofTimestepsSpinnerLabel  matlab.ui.control.Label
        NumberofTimestepsSpinner       matlab.ui.control.Spinner
        MaximumTileThicknessSpinnerLabel  matlab.ui.control.Label
        MaximumTileThicknessSpinner    matlab.ui.control.Spinner
        NumberofSpatialStepsSpinnerLabel  matlab.ui.control.Label
        NumberofSpatialStepsSpinner    matlab.ui.control.Spinner
        MaximumTimeSpinnerLabel        matlab.ui.control.Label
        MaximumTimeSpinner             matlab.ui.control.Spinner
        UIAxes3                        matlab.ui.control.UIAxes
        EnableRadiationCheckBox        matlab.ui.control.CheckBox
        RotationSwitchLabel            matlab.ui.control.Label
        RotationSwitch                 matlab.ui.control.Switch
        UIAxes4                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        menunum % Variable that is 1, 2 or 3 depending on the menu that is selected
    end

    methods (Access = private)
    
        
        function timecheck(app, nx, xmax, tmax, tile, r)
            
            % Intializing counter value
            i=0; 
            % Number of spatial steps (m)
            nxst = nx; 
            % Maximum tile thickness (m)
            thick = xmax; 
            % Maximum time (s)
            tmaxst = tmax; 
            % Image associated with selected tile
            tilename = tile;

            %% Looping through a number of timesteps
            for ntst = 41:20:1001
                
                % Increasing the counter value by 1
                i=i+1; 
                
                % Creating a vector of time steps
                dtst(i) = tmaxst/(ntst-1); 
                
                % disp (['nt = ' num2str(nt) ', dt = ' num2str(dt(i)) ' s']) 
                
                % Forward-differencing method
                [~, ~, u] = shuttlerad(tmaxst, ntst, thick, nxst, 'forward', false, tilename, r); 
                uf(i) = u(end, 1); 
                
                % Backward-differenicng method
                [~, ~, u] = shuttlerad(tmaxst, ntst, thick, nxst, 'backward', false, tilename, r); 
                ub(i) = u(end, 1); 
                
                % Dufort-Frankel method
                [~, ~, u] = shuttlerad(tmaxst, ntst, thick, nxst, 'dufort-frankel', false, tilename, r); 
                ud(i) = u(end, 1); 
                
                % Crank-Nicolson method
                [~, ~, u] = shuttlerad(tmaxst, ntst, thick, nxst, 'crank-nicolson', false, tilename, r); 
                uc(i) = u(end, 1);
                
            end 

            % Plotting the result to determine the most appropriate method and time step
            plot(app.UIAxes, dtst, [uf; ub; ud; uc], 'LineWidth', 1) 
            lgd = legend(app.UIAxes, 'Forward', 'Backward', 'Dufort-Frankel', 'Crank-Nicolson');
            lgd.Location = 'best';
            
        end
        
        function spatialcheck(app, nt, xmax, tmax, tile, r)
            
            % Intializing counter value
            i=0; 
            % Number of time steps (s)
            ntsp = nt; 
            % Maximum tile thickness (m)
            xmaxsp = xmax; 
            % Maximum time (s)
            tmaxsp = tmax; 
            % Image associated with selected tile
            tilename = tile;

            %% Looping through a number of spatial steps
            for nxsp = 3:2:101 
                
                % Increasing the counter value by 1
                i=i+1; 
                
                % Creating a vector of spatial steps
                dxsp(i) = xmaxsp/(nxsp-1); 
                
                % disp (['nt = ' num2str(nt) ', dt = ' num2str(dt(i)) ' s']) 
                
                % Forward-differencing method
                [~, ~, u] = shuttlerad(tmaxsp, ntsp, xmaxsp, nxsp, 'forward', false, tilename, r); 
                uf(i) = u(end, 1); 
                
                % Backward-differencing method
                [~, ~, u] = shuttlerad(tmaxsp, ntsp, xmaxsp, nxsp, 'backward', false, tilename, r); 
                ub(i) = u(end, 1); 
                
                % Dufort-Frankel method
                [~, ~, u] = shuttlerad(tmaxsp, ntsp, xmaxsp, nxsp, 'dufort-frankel', false, tilename, r); 
                ud(i) = u(end, 1); 
                
                % Crank-Nicolson method
                [~, ~, u] = shuttlerad(tmaxsp, ntsp, xmaxsp, nxsp, 'crank-nicolson', false, tilename, r); 
                uc(i) = u(end, 1);
            end 

            %% Plotting the result to determine the most appropriate method and spatial step
            plot(app.UIAxes2, dxsp, [uf; ub; ud; uc], 'LineWidth', 1) 
            lgd = legend(app.UIAxes2, 'Forward', 'Backward', 'Dufort-Frankel', 'Crank-Nicolson');
            lgd.Location = 'best';
            
        end
        
        function comparethickness(app, nt, nx, tmax, image, method, r)
            
            % Intializing counter value
            i=0; 
            % Number of time steps (s)
            ntc = nt; 
            % Number of spatial steps (m)
            nxc = nx; 
            % Maximum time (s)
            tmaxc = tmax; 
            % Suppresses graph
            doplot = false;
            % Image associated with selected tile
            tilename = image;
            % Method selected
            nummethod = method; 

            % Looping across a number of thickness values
            for xmaxc = 0.01:0.01:0.10
                
                % Increasing the counter value by 1
                i=i+1; 
                
                % Creating a vector of thickness values as texts
                xvec{i} = sprintf('%s %s', 'Thickness =', num2str(xmaxc));
                
                % Calling the shuttlerad function to get values of u for each value
                % of xmax (thickness)
                [~, t, u] = shuttlerad(tmaxc, ntc, xmaxc, nxc, nummethod, doplot, tilename, r);
                tn(:,i) = u(:,1); 
                
            end
            
            % Plotting the result to determine the best tile thickness
            plot(app.UIAxes3, t, tn, 'LineWidth', 1)
            lgd = legend(app.UIAxes3,xvec);
            lgd.Location = 'best';
            
        end
        
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Setting the visibility of the axes in the initially unselected menus to off 
            % and the visibility of the axes in the inital menu to on
            app.UIAxes.Visible = 'off'; 
            app.UIAxes2.Visible = 'off';
            app.UIAxes3.Visible = 'off';
            app.UIAxes4.Visible = 'on';
            
            app.menunum = 1; % Variable is 1 as the first menu is selected
        end

        % Menu selected function: StabilityCheckerMenu
        function StabilityCheckerMenuSelected(app, event)
            
            % Setting the visibility of various components to off or on depending on whether
            % they should be visible to the user or not when this menu is selected
            app.SelectMethodDropDown.Visible = 'off';
            app.SelectMethodDropDownLabel.Visible = 'off';
            app.UIAxes.Visible = 'on';
            app.UIAxes2.Visible = 'on';
            app.UIAxes3.Visible = 'off';
            app.UIAxes4.Visible = 'on';
            app.MaximumTileThicknessSpinner.Visible = 'on';
            app.MaximumTileThicknessSpinnerLabel.Visible = 'on';
            app.RotationSwitch.Visible = 'off';
            app.RotationSwitchLabel.Visible = 'off';
            
            % Hiding Axes 3 and 4 to prevent them from obstructing the view of the visible axes
            app.UIAxes3.Position = [0 0 0 0];
            app.UIAxes4.Position = [0 0 0 0];
            
            app.menunum = 2; % Variable is 2 as the second menu is selected
            
        end

        % Menu selected function: WaterfallPlotterMenu
        function WaterfallPlotterMenuSelected(app, event)
            
            % Setting the visibility of various components to off or on depending on whether
            % they should be visible to the user or not when this menu is selected
            app.SelectMethodDropDown.Visible = 'on';
            app.SelectMethodDropDownLabel.Visible = 'on';
            app.UIAxes.Visible = 'off';
            app.UIAxes2.Visible = 'off';
            app.UIAxes3.Visible = 'off';
            app.UIAxes4.Visible = 'on';
            app.MaximumTileThicknessSpinner.Visible = 'on';
            app.MaximumTileThicknessSpinnerLabel.Visible = 'on';
            app.RotationSwitch.Visible = 'on';
            app.RotationSwitchLabel.Visible = 'on';
            
            % Enabling the axes to automatically resize based on the size of the figure window
            position = app.UIFigure.Position;
            app.UIAxes4.Position = [12 125 position(3)-20 position(4)-175];
            
            app.menunum = 1; % Variable is 1 as the first menu is selected
            
        end

        % Menu selected function: ThicknessComparerMenu
        function ThicknessComparerMenuSelected(app, event)
            
            % Setting the visibility of various components to off or on depending on whether
            % they should be visible to the user or not when this menu is selected
            app.SelectMethodDropDown.Visible = 'on';
            app.SelectMethodDropDownLabel.Visible = 'on';
            app.UIAxes.Visible = 'off';
            app.UIAxes2.Visible = 'off';
            app.UIAxes3.Visible = 'on';
            app.UIAxes4.Visible = 'off';
            app.MaximumTileThicknessSpinner.Visible = 'off';
            app.MaximumTileThicknessSpinnerLabel.Visible = 'off';
            app.RotationSwitch.Visible = 'off';
            app.RotationSwitchLabel.Visible = 'off';
            
            % Enabling the axes to automatically resize based on the size of the figure window
            position = app.UIFigure.Position;
            app.UIAxes3.Position = [11 125 position(3)-20 position(4)-175];
            
            app.menunum = 3; % Variable is 3 as the third menu is selected
            
        end

        % Button pushed function: RUNPROGRAMButton
        function RUNPROGRAMButtonPushed(app, event)
            % Disabling the figure while programs are being run
            set(findall(app.UIFigure, '-property', 'Enable'), 'Enable', 'off')
            
            tilenum = app.SelectTileDropDown.Value; % selected tile number
            imagename = strcat('temp',tilenum);
            imagefile = strcat(imagename,'.mat');
            
            % Determining whether the plot image of the temperature for the selected tile needs to be traced
            if exist(imagefile) ~= 2
                plottemp
            end
            
            methodstr = app.SelectMethodDropDown.Value; % selected solution method
            
            % Renaming the method string to be compatible with all functions
            switch methodstr
                case 'Forward Differencing'
                    method = 'forward';
                case 'DuFort-Frankel'
                    method = 'dufort-frankel';
                case 'Backward Differencing'
                    method = 'backward';
                case 'Crank-Nicolson'
                    method = 'crank-nicolson';
            end
            
            tmax = app.MaximumTimeSpinner.Value; % maximum time (s)
            nt = app.NumberofTimestepsSpinner.Value; % number of timesteps
            nx = app.NumberofSpatialStepsSpinner.Value; % number of spatial steps
            r = app.EnableRadiationCheckBox.Value; % boolean to determine if radiation is included or excluded
            
            % Determining what should be done based on what menu is selected
            if app.menunum == 1
                xmax = app.MaximumTileThicknessSpinner.Value; % maximum tile thickness (m)
                
                % Running the shuttlerad function
                [x,t,u] = shuttlerad(tmax, nt, xmax, nx, method, false, imagename, r);
                
                % Creating a waterfall plot of thickness, time and temperature
                waterfall(app.UIAxes4, x, t, u)
                axis tight
                set(app.UIAxes4, 'XTickMode', 'auto', 'XTickLabelMode', 'auto')
                set(app.UIAxes4, 'YTickMode', 'auto', 'YTickLabelMode', 'auto')
                set(app.UIAxes4, 'ZTickMode', 'auto', 'ZTickLabelMode', 'auto')
                app.UIAxes4.ZLim = [0 inf];
                app.UIAxes4.YLim = [0 t(end)];
                app.UIAxes4.XLim = [0 x(end)];
                shading(app.UIAxes4, 'interp')
                xlabel(app.UIAxes4,'\itx\rm - m'); 
                ylabel(app.UIAxes4,'\itt\rm - s'); 
                zlabel(app.UIAxes4,'\itu\rm - deg C');
                
                close(gcf)
            elseif app.menunum == 2
                xmax = app.MaximumTileThicknessSpinner.Value; % maximum tile thickness (m)
               
                % Running the timestep stability checker function
                timecheck(app, nx, xmax, tmax, imagename, r)
                
                % Running the spatial step stability checker function
                spatialcheck(app, nt, xmax, tmax, imagename, r)
            else 
                % Running the thickness comparer function
                comparethickness(app, nt, nx, tmax, imagename, method, r)
            end
            
            % Enabling the figure after all programs are run
            set(findall(app.UIFigure, '-property', 'Enable'), 'Enable', 'on')
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            % Disabling the figure until relevant axes are cleared
            set(findall(app.UIFigure, '-property', 'Enable'), 'Enable', 'off')
            
            % Clearing the relevant axes based on which menu is selected
            if app.menunum == 1
                cla(app.UIAxes4)
                
                % Resetting rotation switch
                app.RotationSwitch.Value = 'Off';
                rotate3d(app.UIAxes4, 'off');
            elseif app.menunum == 2
                cla(app.UIAxes)
                cla(app.UIAxes2)
                legend(app.UIAxes, 'off')
                legend(app.UIAxes2, 'off')
            else 
                cla(app.UIAxes3)
                legend(app.UIAxes3, 'off')
            end
            
             % Enabling the figure after all steps are complete
            set(findall(app.UIFigure, '-property', 'Enable'), 'Enable', 'on')
        end

        % Value changed function: RotationSwitch
        function RotationSwitchValueChanged(app, event)
            value = app.RotationSwitch.Value;
            
            % Enabling or disabling the ability to rotate the waterfall plot based on the switch value
            if strcmp('On',value)
                rotate3d(app.UIAxes4, 'on');
            else
                rotate3d(app.UIAxes4, 'off');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.WaterfallPlotterMenu = uimenu(app.UIFigure);
            app.WaterfallPlotterMenu.MenuSelectedFcn = createCallbackFcn(app, @WaterfallPlotterMenuSelected, true);
            app.WaterfallPlotterMenu.Text = 'Waterfall Plotter';
            app.StabilityCheckerMenu = uimenu(app.UIFigure);
            app.StabilityCheckerMenu.MenuSelectedFcn = createCallbackFcn(app, @StabilityCheckerMenuSelected, true);
            app.StabilityCheckerMenu.Text = 'Stability Checker';
            app.ThicknessComparerMenu = uimenu(app.UIFigure);
            app.ThicknessComparerMenu.MenuSelectedFcn = createCallbackFcn(app, @ThicknessComparerMenuSelected, true);
            app.ThicknessComparerMenu.Text = 'Thickness Comparer';
            app.SelectTileDropDownLabel = uilabel(app.UIFigure);
            app.SelectTileDropDownLabel.Text = 'Select Tile';
            app.SelectTileDropDown = uidropdown(app.UIFigure);
            app.SelectTileDropDown.Items = {'502', '590', '468', '597', '480', '850', '711', '730'};
            app.SelectTileDropDown.Value = '502';
            app.SelectMethodDropDownLabel = uilabel(app.UIFigure);
            app.SelectMethodDropDownLabel.Text = 'Select Method';
            app.SelectMethodDropDown = uidropdown(app.UIFigure);
            app.SelectMethodDropDown.Items = {'Forward Differencing', 'DuFort-Frankel', 'Backward Differencing', 'Crank-Nicolson'};
            app.SelectMethodDropDown.Value = 'Forward Differencing';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Timestep Stability Checker')
            xlabel(app.UIAxes, 'Timestep (s)')
            ylabel(app.UIAxes, 'Temperature (deg C)')
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YLim = [100 200];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [0 10 20 30 40 50 60 70 80 90 100];
            app.UIAxes.YTick = [100 110 120 130 140 150 160 170 180 190 200];
            app.UIAxes.ZTick = [0 0.5 1];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Spatial Step Stability Checker')
            xlabel(app.UIAxes2, 'Spatial Step (m)')
            ylabel(app.UIAxes2, 'Temperature (deg C)')
            app.UIAxes2.XLim = [0 0.025];
            app.UIAxes2.YLim = [100 200];
            app.UIAxes2.ZLim = [0 1];
            app.UIAxes2.CLim = [0 1];
            app.UIAxes2.XTick = [0 0.005 0.01 0.015 0.02 0.025];
            app.UIAxes2.YTick = [100 110 120 130 140 150 160 170 180 190 200];
            app.UIAxes2.ZTick = [0 0.5 1];
            app.InputsPanel = uipanel(app.UIFigure);
            app.InputsPanel.Title = 'Inputs';
            app.RUNPROGRAMButton = uibutton(app.InputsPanel, 'push');
            app.RUNPROGRAMButton.ButtonPushedFcn = createCallbackFcn(app, @RUNPROGRAMButtonPushed, true);
            app.RUNPROGRAMButton.Text = 'RUN PROGRAM';
            app.CLEARButton = uibutton(app.InputsPanel, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.NumberofTimestepsSpinnerLabel = uilabel(app.InputsPanel);
            app.NumberofTimestepsSpinnerLabel.Text = 'Number of Timesteps';
            app.NumberofTimestepsSpinner = uispinner(app.InputsPanel);
            app.NumberofTimestepsSpinner.Limits = [1 Inf];
            app.NumberofTimestepsSpinner.Value = 501;
            app.MaximumTileThicknessSpinnerLabel = uilabel(app.InputsPanel);
            app.MaximumTileThicknessSpinnerLabel.Text = 'Maximum Tile Thickness';
            app.MaximumTileThicknessSpinner = uispinner(app.InputsPanel);
            app.MaximumTileThicknessSpinner.Step = 0.01;
            app.MaximumTileThicknessSpinner.Limits = [0.01 Inf];
            app.MaximumTileThicknessSpinner.Value = 0.05;
            app.NumberofSpatialStepsSpinnerLabel = uilabel(app.InputsPanel);
            app.NumberofSpatialStepsSpinnerLabel.Text = 'Number of Spatial Steps';
            app.NumberofSpatialStepsSpinner = uispinner(app.InputsPanel);
            app.NumberofSpatialStepsSpinner.Limits = [1 Inf];
            app.NumberofSpatialStepsSpinner.Value = 21;
            app.MaximumTimeSpinnerLabel = uilabel(app.InputsPanel);
            app.MaximumTimeSpinnerLabel.Text = 'Maximum Time';
            app.MaximumTimeSpinner = uispinner(app.InputsPanel);
            app.MaximumTimeSpinner.Limits = [1 Inf];
            app.MaximumTimeSpinner.Value = 4000;
            app.UIAxes3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes3, 'Time (s)')
            ylabel(app.UIAxes3, 'Temperature (deg C)')
            app.UIAxes3.XLim = [0 4000];
            app.UIAxes3.YLim = [0 1000];
            app.UIAxes3.ZLim = [0 1];
            app.UIAxes3.CLim = [0 1];
            app.UIAxes3.XTick = [0 500 1000 1500 2000 2500 3000 3500 4000];
            app.UIAxes3.YTick = [0 200 400 600 800 1000];
            app.UIAxes3.ZTick = [0 0.5 1];
            app.EnableRadiationCheckBox = uicheckbox(app.UIFigure);
            app.EnableRadiationCheckBox.Text = 'Enable Radiation';
            app.RotationSwitchLabel = uilabel(app.UIFigure);
            app.RotationSwitchLabel.Text = 'Rotation';
            app.RotationSwitch = uiswitch(app.UIFigure, 'slider');
            app.RotationSwitch.ValueChangedFcn = createCallbackFcn(app, @RotationSwitchValueChanged, true);
            app.UIAxes4 = uiaxes(app.UIFigure);
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
        end
    end

    methods (Access = public)

        % Construct app
        function app = TempSimulator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end